classdef FixedWingDeflectionGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        deflectionPlot                  matlab.ui.control.UIAxes
        PlotDeflectionButton            matlab.ui.control.Button
        maxDeflection                   matlab.ui.control.NumericEditField
        WingCFBoomOuterRadiusmmLabel    matlab.ui.control.Label
        WingCFBoomOuterRadiusmm         matlab.ui.control.NumericEditField
        WingCFBoomInnerRadiusmmLabel    matlab.ui.control.Label
        WingCFBoomInnerRadiusmm         matlab.ui.control.NumericEditField
        TotalLengthofWingsCFboommLabel  matlab.ui.control.Label
        LengthofWingCFBoom              matlab.ui.control.NumericEditField
        MaximumDeflectionmmLabel        matlab.ui.control.Label
        TotalMassofAirframekginclcomponentsonwingLabel  matlab.ui.control.Label
        TotalMassofAirframe             matlab.ui.control.NumericEditField
        TotalMassofComponentsMountedonWingskgEditFieldLabel  matlab.ui.control.Label
        WingComponentsMass              matlab.ui.control.NumericEditField
        DistanceFuselagesCenterWingComponentsmLabel  matlab.ui.control.Label
        WingComponentsMountDistance     matlab.ui.control.NumericEditField
        FIXEDWINGAIRFRAMEWINGDEFLECTIONMODELLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        NumberofGsofaccelerationEditFieldLabel  matlab.ui.control.Label
        NumberofGs                      matlab.ui.control.NumericEditField
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PlotDeflectionButton
        function PlotDeflectionButtonPushed(app, event)
            num_Gs = app.NumberofGs.Value;
            mt = app.TotalMassofAirframe.Value; %total mass of airframe (kg)
            me = app.WingComponentsMass.Value; %total mass of components mounted on both wings (kg)
            CF_tube_length = app.LengthofWingCFBoom.Value / 2.0; %length of CF tube under one wing (m)
            D_wing_mounts = app.WingComponentsMountDistance.Value; %Distance between the center of fuselage to wing components
            CF_tube_rout = app.WingCFBoomOuterRadiusmm.Value/1000.0; % in meters
            CF_tube_rin = app.WingCFBoomInnerRadiusmm.Value/1000.0;
            
            G = 9.81;
            P1 = (mt-me)*(G + num_Gs)/2;
            P2 = me*(G + num_Gs)/2;
            
            E = 15.63 * 10^9; % Young's modulus of carbon fiber in the boom determined using the rule of mixtures
            I = pi / 4 * (CF_tube_rout^4 - CF_tube_rin^4); % Area moment of intertia of the CF tube
            
            MAX_PLOT_POINTS = 1000;
            
            x = linspace(0,CF_tube_length,MAX_PLOT_POINTS);
            % y = zeros(MAX_PLOT_POINTS);
            index = 1;
            index_wing_mount = 1;
            
            while (index <= MAX_PLOT_POINTS)
                if (x(index) < D_wing_mounts )
                    y(index) = ((-P2*D_wing_mounts + P1*CF_tube_length / 2)*x(index)^2 / 2 ...
                        - (P1 - P2)*x(index)^3 / 6 + P1 * x(index)^4 / (24*CF_tube_length))/(E*I);
                    index = index + 1;
                    index_wing_mount = index_wing_mount + 1;
            
                elseif (x(index) >= D_wing_mounts)
                    y(index) = ((-P2*D_wing_mounts + P1*CF_tube_length / 2)*x(index)^2 / 2 ...
                        - (P1 - P2)*x(index)^3 / 6 - P2 * (x(index)-D_wing_mounts).^3 / 6 + ...
                        P1 * x(index)^4 / (24*CF_tube_length))/(E*I);
                    index = index + 1;
                end
            end
            
            plot(app.deflectionPlot, x, y.*1000, x(index_wing_mount), y(index_wing_mount)*1000, 'r*');
            
            app.maxDeflection.Value = max(abs(y))*1000;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.deflectionPlot = uiaxes(app.UIFigure);
            title(app.deflectionPlot, 'Wing''s Boom Deflection vs Distance along Boom')
            xlabel(app.deflectionPlot, 'Distance along boom (m)')
            ylabel(app.deflectionPlot, 'Deflection (mm)')
            app.PlotDeflectionButton = uibutton(app.UIFigure, 'push');
            app.PlotDeflectionButton.ButtonPushedFcn = createCallbackFcn(app, @PlotDeflectionButtonPushed, true);
            app.PlotDeflectionButton.Text = 'Plot Deflection';
            app.maxDeflection = uieditfield(app.UIFigure, 'numeric');
            app.maxDeflection.ValueDisplayFormat = '%5.2f';
            app.maxDeflection.Editable = 'off';
            app.WingCFBoomOuterRadiusmmLabel = uilabel(app.UIFigure);
            app.WingCFBoomOuterRadiusmmLabel.Text = 'Wing CF Boom Outer Radius (mm)';
            app.WingCFBoomOuterRadiusmm = uieditfield(app.UIFigure, 'numeric');
            app.WingCFBoomOuterRadiusmm.Limits = [0 Inf];
            app.WingCFBoomOuterRadiusmm.Value = 6;
            app.WingCFBoomInnerRadiusmmLabel = uilabel(app.UIFigure);
            app.WingCFBoomInnerRadiusmmLabel.Text = 'Wing CF Boom Inner Radius (mm)';
            app.WingCFBoomInnerRadiusmm = uieditfield(app.UIFigure, 'numeric');
            app.WingCFBoomInnerRadiusmm.Limits = [0 Inf];
            app.WingCFBoomInnerRadiusmm.Value = 4;
            app.TotalLengthofWingsCFboommLabel = uilabel(app.UIFigure);
            app.TotalLengthofWingsCFboommLabel.Text = 'Total Length of Wing''s CF boom (m)';
            app.LengthofWingCFBoom = uieditfield(app.UIFigure, 'numeric');
            app.LengthofWingCFBoom.Limits = [0 Inf];
            app.LengthofWingCFBoom.Value = 1;
            app.MaximumDeflectionmmLabel = uilabel(app.UIFigure);
            app.MaximumDeflectionmmLabel.Text = 'Maximum Deflection (mm)';
            app.TotalMassofAirframekginclcomponentsonwingLabel = uilabel(app.UIFigure);
            app.TotalMassofAirframekginclcomponentsonwingLabel.Text = 'Total Mass of Airframe (kg) (incl. components on wing)';
            app.TotalMassofAirframe = uieditfield(app.UIFigure, 'numeric');
            app.TotalMassofAirframe.Limits = [0 Inf];
            app.TotalMassofAirframe.Value = 4;
            app.TotalMassofComponentsMountedonWingskgEditFieldLabel = uilabel(app.UIFigure);
            app.TotalMassofComponentsMountedonWingskgEditFieldLabel.Text = 'Total Mass of Components Mounted on Wings (kg)';
            app.WingComponentsMass = uieditfield(app.UIFigure, 'numeric');
            app.WingComponentsMass.Limits = [0 Inf];
            app.DistanceFuselagesCenterWingComponentsmLabel = uilabel(app.UIFigure);
            app.DistanceFuselagesCenterWingComponentsmLabel.Text = 'Distance: Fuselage''s Center - Wing Components (m)';
            app.WingComponentsMountDistance = uieditfield(app.UIFigure, 'numeric');
            app.WingComponentsMountDistance.Limits = [0 Inf];
            app.FIXEDWINGAIRFRAMEWINGDEFLECTIONMODELLabel = uilabel(app.UIFigure);
            app.FIXEDWINGAIRFRAMEWINGDEFLECTIONMODELLabel.Text = 'FIXED-WING AIRFRAME: WING DEFLECTION MODEL';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'If you have any questions about this GUI, feel free to contact Rishabh Singal at rishabhsingal01@gmail.com';
            app.NumberofGsofaccelerationEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofGsofaccelerationEditFieldLabel.Text = 'Number of Gs of acceleration';
            app.NumberofGs = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FixedWingDeflectionGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end